classdef Maquina03MNED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        RelatorioMenu          matlab.ui.container.Menu
        PaginaWebMenu          matlab.ui.container.Menu
        SairMenu               matlab.ui.container.Menu
        SobreMenu              matlab.ui.container.Menu
        AutoresMenu            matlab.ui.container.Menu
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        Image                  matlab.ui.control.Image
        Switch_7               matlab.ui.control.Switch
        MtodosNumricosPanel    matlab.ui.container.Panel
        Lamp_6                 matlab.ui.control.Lamp
        Switch_6               matlab.ui.control.Switch
        Lamp_5                 matlab.ui.control.Lamp
        Switch_5               matlab.ui.control.Switch
        Lamp_4                 matlab.ui.control.Lamp
        Switch_4               matlab.ui.control.Switch
        Lamp_3                 matlab.ui.control.Lamp
        Switch_3               matlab.ui.control.Switch
        Lamp_2                 matlab.ui.control.Lamp
        Switch_2               matlab.ui.control.Switch
        Lamp                   matlab.ui.control.Lamp
        Switch                 matlab.ui.control.Switch
        MtododoPontoMdioLabel  matlab.ui.control.Label
        ODE45matlabLabel       matlab.ui.control.Label
        MtodoRK4Label          matlab.ui.control.Label
        MtodoRK2Label          matlab.ui.control.Label
        MtododeEulerMLabel     matlab.ui.control.Label
        MtododeEulerLabel      matlab.ui.control.Label
        GrelhaCheckBox         matlab.ui.control.CheckBox
        ResetButton            matlab.ui.control.Button
        ExecutarButton         matlab.ui.control.Button
        DadosdoPVIPanel        matlab.ui.container.Panel
        randomButton           matlab.ui.control.Button
        eY0                    matlab.ui.control.NumericEditField
        aEditFieldLabel_3      matlab.ui.control.Label
        eN                     matlab.ui.control.Spinner
        nSpinnerLabel          matlab.ui.control.Label
        aEditFieldLabel_2      matlab.ui.control.Label
        eB                     matlab.ui.control.NumericEditField
        eA                     matlab.ui.control.NumericEditField
        aEditFieldLabel        matlab.ui.control.Label
        eF                     matlab.ui.control.EditField
        ftyEditFieldLabel      matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        TabGroup               matlab.ui.container.TabGroup
        GrficoTab              matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        Tabeladevalores        matlab.ui.container.Tab
        UITable                matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app,[])
        end

        % Callback function: ExecutarButton, eN
        function ExecutarButtonPushed(app, event)
            syms y(t)
            strF = app.eF.Value;
            f=@(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            y0= app.eY0.Value;
           
            
            erroFuncao = MException("MATLAB:bAtualizar:badF", ...
                                    "Introduza uma função em t e y");

                erroA = MException("MATLAB:bAtualizar:badA", ...
                                   "Introduza  um número real");
                
                erroB = MException("MATLAB:bAtualizar:badB", ...
                                   "Introduza um b > a");
                
                erroY0 = MException("MATLAB:bAtualizar:badY0", ...
                                   "Introduza  um número real");
                erroN = MException("MATLAB:bAtualizar:badN", ...
                                   "Introduza  um número real positivo " + ...
                                   "e maior ou igual a 2") ;

          try
                try
                    z = f(t,y);
                    app.eF.BackgroundColor = "w";
                catch 
                    app.eF.BackgroundColor = "r";
                    throw(erroFuncao);   
                
                end
                    
                
                if isscalar(a) && isreal(a)
                    app.eA.BackgroundColor = "w";
                else
                    app.eA.BackgroundColor = "r";
                    throw(erroA);
                end
                    
                
                if isscalar(b) && isreal(b) && b>a
                     app.eB.BackgroundColor = "w";
                else
                     app.eB.BackgroundColor = "r";
                     throw(erroB);
                end
                    
                 
                 if isscalar(n) && isreal(n) && (n>=2)
                      app.eN.BackgroundColor = "w";
                 else
                      app.eN.BackgroundColor = "r";
                      throw(erroN);
                 end
                 
                    
                 if isscalar(y0) && isreal(y0)
                      app.eY0.BackgroundColor = "w";
                 else
                      app.eY0.BackgroundColor = "r";
                      throw(erroY0);
                 end


                yEuler = Euler(f,a,b,n,y0);
                yEulerM = EulerM(f,a,b,n,y0);
                yRK2 = RK2(f,a,b,n,y0);
                yRK4 = RK4(f,a,b,n, y0);
                yODE45 = ODE45 (f,a,b,n,y0);
                yPontoMedio = PontoMedio(f,a,b,n,y0);
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                
                g =@(t) eval(vectorize(char(sExata)));
                
                h = (b-a)/n;
                t = a:h:b;
                yExata = g(t);
               
                erroEuler = abs(yExata-yEuler);
                erroEulerM = abs(yExata-yEulerM);
                erroRK2 = abs(yExata-yRK2);
                erroRK4 = abs(yExata-yRK4);
                erroODE45 = abs(yExata-yODE45);
                erroPontoMedio = abs(yExata-yPontoMedio);
                
                
                plot(app.UIAxes,t, yExata,"-ro")
                hold (app.UIAxes,"on")
                legenda = {'Exata'};
                tabela = [t.', yExata.'];
                nomeColunas = {'t', 'Exata'};

                if app.Switch.Value == "On"

                        plot(app.UIAxes,t,yEuler,"-g*")
                        legenda = {legenda{:},'Euler'};
                        tabela = [tabela, yEuler.', erroEuler.'];
                        nomeColunas = {nomeColunas{:}, 'Euler',  'ErroEuler'};

                end
                if app.Switch_2.Value == "On"
                    
                        plot(app.UIAxes,t,yEulerM,"-m*")
                        legenda = {legenda{:},'EulerM'};
                        tabela = [tabela, yEulerM.', erroEulerM.'];
                        nomeColunas = {nomeColunas{:}, 'EulerM',  'ErroEulerM'};

                end
                if app.Switch_3.Value == "On"

                        plot(app.UIAxes,t,yRK2,"-b+")
                        legenda = {legenda{:},'RK2'};
                        tabela = [tabela, yRK2.', erroRK2.'];
                        nomeColunas = {nomeColunas{:}, 'RK2',  'ErroRK2'};

                end
                if app.Switch_4.Value == "On"
                    
                        plot(app.UIAxes,t,yRK4,"-k+")
                        legenda = {legenda{:},'RK4'};
                        tabela = [tabela, yRK4.', erroRK4.'];
                        nomeColunas = {nomeColunas{:}, 'RK4',  'ErroRK4'};

                end
                if app.Switch_5.Value == "On"

                        plot(app.UIAxes,t,yODE45,"-y+")
                        legenda = {legenda{:},'ODE45'};
                        tabela = [tabela, yODE45.', erroODE45.'];
                        nomeColunas = {nomeColunas{:}, 'ODE45',  'ErroODE45'};
                 
                end
                if app.Switch_6.Value == "On"

                        plot(app.UIAxes,t,yPontoMedio,"-c+")
                        legenda = {legenda{:},'PontoMédio'};
                        tabela = [tabela, yPontoMedio.', erroPontoMedio.'];
                        nomeColunas =  {nomeColunas{:}, 'PontoMedio',  'ErroPontoMedio'};
                        
                end
                if app.Switch_7.Value == "On"

                         plot(app.UIAxes,t,yEuler,"-g*")
                         plot(app.UIAxes,t,yEulerM,"-m*")
                         plot(app.UIAxes,t,yRK2,"-b+")
                         plot(app.UIAxes,t,yRK4,"-k+")
                         plot(app.UIAxes,t,yODE45,"-y+")
                         plot(app.UIAxes,t,yPontoMedio,"-c+")
                         legenda =  {'Exata','Euler','EulerM','RK2','RK4','ODE45','PontoMedio'};
                         tabela = [t.', yExata.', yEuler.',yEulerM.', ...
                             yRK2.', yRK4.',yODE45.',yPontoMedio.', erroEuler.', erroEulerM.',... 
                             erroRK2.', erroRK4.',erroODE45.',erroPontoMedio.'];
                        nomeColunas = {'t', 'Exata', 'Euler', 'EulerM',...
                            'RK2', 'RK4','ODE45','PontoMedio', 'ErroEuler', 'ErroEulerM',...
                            'ErroRK2', 'ErroRK4','ErroODE45','ErroPontoMedio'};
                end
                
                T = array2table(tabela);
                app.UITable.Data = T;

                app.UITable.ColumnName= nomeColunas;

                hold (app.UIAxes,"off")
                grid (app.UIAxes,"on")

                legend(app.UIAxes,legenda);

            catch Me
                errordlg(Me.message,"ERRO","modal")
          end
        end

        % Value changed function: GrelhaCheckBox
        function GrelhaCheckBoxValueChanged(app, event)
            value = app.GrelhaCheckBox.Value;
            if value
                grid(app.UIAxes,"on")
            else
                grid(app.UIAxes,"off")
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN, "Sim")
                delete(app.UIFigure)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %delete(app)
            SairMenuSelected(app, event)
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open("")                                                                           %POR AQUI RELATORIO
        end

        % Menu selected function: PaginaWebMenu
        function PaginaWebMenuSelected(app, event)
            web("https://www.youtube.com/watch?v=dQw4w9WgXcQ");
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autores
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
            % METODO DE EULER
            if app.Switch.Value == "On"
                app.Lamp.Color = "green";
            else
                app.Lamp.Color = "red";
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            
            % METODO DE EULER MELHORADO
            if app.Switch_2.Value == "On"
                app.Lamp_2.Color = "green";
            else
                app.Lamp_2.Color = "red";
            end
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            
            % METODO DE RK2
            if app.Switch_3.Value == "On"
                app.Lamp_3.Color = "green";
            else
                app.Lamp_3.Color = "red";
            end
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            
            % METODO DE RK4
            if app.Switch_4.Value == "On"
                app.Lamp_4.Color = "green";
            else
                app.Lamp_4.Color = "red";
            end
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            
            % METODO DE ODE45
            if app.Switch_5.Value == "On"
                app.Lamp_5.Color = "green";
            else
                app.Lamp_5.Color = "red";
            end
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged(app, event)
            
            % METODO DO PONTO MEDIO
            if app.Switch_6.Value == "On"
                app.Lamp_6.Color = "green";
            else
                app.Lamp_6.Color = "red";
            end
        end

        % Value changed function: Switch_7
        function Switch_7ValueChanged(app, event)
            
            if app.Switch_7.Value == "Tudo"
                app.Switch.Value   = "On";
                app.Lamp.Color ="green"  ;
                app.Switch_2.Value = "On";
                app.Lamp_2.Color ="green";
                app.Switch_3.Value = "On";
                app.Lamp_3.Color ="green";
                app.Switch_4.Value = "On";
                app.Lamp_4.Color ="green";
                app.Switch_5.Value = "On";
                app.Lamp_5.Color ="green";
                app.Switch_6.Value = "On";
                app.Lamp_6.Color ="green";
            else
                app.Switch.Value   = "Off";
                app.Lamp.Color = "red"    ;
                app.Switch_2.Value = "Off";
                app.Lamp_2.Color = "red"  ;
                app.Switch_3.Value = "Off";
                app.Lamp_3.Color = "red"  ;
                app.Switch_4.Value = "Off";
                app.Lamp_4.Color = "red"  ;
                app.Switch_5.Value = "Off";
                app.Lamp_5.Color = "red"  ;
                app.Switch_6.Value = "Off";
                app.Lamp_6.Color = "red"  ;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             
            app.eA.Value = 0;  
            app.eB.Value = 0;   
            app.eN.Value = 2;   
            app.eY0.Value = 0;  
            cla(app.UIAxes);
            legenda = {'Exata'};
            tabela = [t.', yExata.'];
            nomeColunas = {'t', 'Exata'};
        end

        % Button pushed function: randomButton
        function randomButtonPushed(app, event)
            %criar valores random para os campos de input
            min_value = 2;
            max_value = 20;

            app.eA.Value = randi([min_value, max_value]);
            app.eB.Value = randi([min_value, max_value]);
            app.eN.Value = randi([min_value, max_value]);
            app.eY0.Value = randi([min_value, max_value]); 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {726, 726};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.RelatorioMenu = uimenu(app.FileMenu);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatório';
            app.PaginaWebMenu = uimenu(app.FileMenu);
            app.PaginaWebMenu.MenuSelectedFcn = createCallbackFcn(app, @PaginaWebMenuSelected, true);
            app.PaginaWebMenu.Text = 'PáginaWeb';
            app.SairMenu = uimenu(app.FileMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.UIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) =';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Value = 'y+exp(3*t)';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eA.Value = 1;
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.eN = uispinner(app.DadosdoPVIPanel);
            app.eN.Limits = [1 Inf];
            app.eN.ValueDisplayFormat = '%.0f';
            app.eN.ValueChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.eN.Value = 2;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'y0';
            app.eY0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eY0.Value = 2;
            app.randomButton = uibutton(app.DadosdoPVIPanel, 'push');
            app.randomButton.ButtonPushedFcn = createCallbackFcn(app, @randomButtonPushed, true);
            app.randomButton.Text = 'random';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GrelhaCheckBox = uicheckbox(app.LeftPanel);
            app.GrelhaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrelhaCheckBoxValueChanged, true);
            app.GrelhaCheckBox.Text = 'Grelha';
            app.GrelhaCheckBox.Value = true;
            app.MtodosNumricosPanel = uipanel(app.LeftPanel);
            app.MtodosNumricosPanel.Title = 'Métodos Numéricos';
            app.MtododeEulerLabel = uilabel(app.MtodosNumricosPanel);
            app.MtododeEulerLabel.Text = 'Método de Euler';
            app.MtododeEulerMLabel = uilabel(app.MtodosNumricosPanel);
            app.MtododeEulerMLabel.Text = 'Método de Euler. M';
            app.MtodoRK2Label = uilabel(app.MtodosNumricosPanel);
            app.MtodoRK2Label.Text = 'Método RK2';
            app.MtodoRK4Label = uilabel(app.MtodosNumricosPanel);
            app.MtodoRK4Label.Text = 'Método RK4';
            app.ODE45matlabLabel = uilabel(app.MtodosNumricosPanel);
            app.ODE45matlabLabel.Text = 'ODE45 (matlab)';
            app.MtododoPontoMdioLabel = uilabel(app.MtodosNumricosPanel);
            app.MtododoPontoMdioLabel.Text = 'Método do Ponto Médio';
            app.Switch = uiswitch(app.MtodosNumricosPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.MtodosNumricosPanel);
            app.Switch_2 = uiswitch(app.MtodosNumricosPanel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Lamp_2 = uilamp(app.MtodosNumricosPanel);
            app.Switch_3 = uiswitch(app.MtodosNumricosPanel, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Lamp_3 = uilamp(app.MtodosNumricosPanel);
            app.Switch_4 = uiswitch(app.MtodosNumricosPanel, 'slider');
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Lamp_4 = uilamp(app.MtodosNumricosPanel);
            app.Switch_5 = uiswitch(app.MtodosNumricosPanel, 'slider');
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Lamp_5 = uilamp(app.MtodosNumricosPanel);
            app.Switch_6 = uiswitch(app.MtodosNumricosPanel, 'slider');
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
            app.Lamp_6 = uilamp(app.MtodosNumricosPanel);
            app.Switch_7 = uiswitch(app.LeftPanel, 'slider');
            app.Switch_7.Items = {'', 'Tudo'};
            app.Switch_7.ValueChangedFcn = createCallbackFcn(app, @Switch_7ValueChanged, true);
            app.Switch_7.Value = '';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo-isec.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.GrficoTab = uitab(app.TabGroup);
            app.GrficoTab.Title = 'Gráfico';
            app.UIAxes = uiaxes(app.GrficoTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tabeladevalores = uitab(app.TabGroup);
            app.Tabeladevalores.Title = 'Tabela de valores';
            app.UITable = uitable(app.Tabeladevalores);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina03MNED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end